classdef piano < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Label_sEMG_MVC                  matlab.ui.control.Label
        sEMGMVCsensortxtButton          matlab.ui.control.Button
        SampleRatesPanel                matlab.ui.container.Panel
        GridLayout_3                    matlab.ui.container.GridLayout
        Base_Label                      matlab.ui.control.Label
        FrameSIze_EditField             matlab.ui.control.NumericEditField
        FrameSIzeLabel                  matlab.ui.control.Label
        Base_EditField                  matlab.ui.control.NumericEditField
        MotionCapture_EditField         matlab.ui.control.NumericEditField
        BloodOxygention_EditField       matlab.ui.control.NumericEditField
        MotionCaptureLabel              matlab.ui.control.Label
        BloodOxygention_Label           matlab.ui.control.Label
        sEMG_Label                      matlab.ui.control.Label
        sEMG_EditField                  matlab.ui.control.NumericEditField
        MotionCaptureMarkerTolerancePanel  matlab.ui.container.Panel
        GridLayout_2                    matlab.ui.container.GridLayout
        MotionCaptureTol_Z_EditField    matlab.ui.control.NumericEditField
        MotionCaptureTol_Y_EditField    matlab.ui.control.NumericEditField
        MotionCaptureTol_X_EditField    matlab.ui.control.NumericEditField
        ZLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        MotionCaptureMarkerCoordinatesPanel  matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        MotionCapture_Z_EditField       matlab.ui.control.NumericEditField
        MotionCapture_Y_EditField       matlab.ui.control.NumericEditField
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        MotionCapture_X_EditField       matlab.ui.control.NumericEditField
        SynchronizeButton               matlab.ui.control.Button
        Label_MotionCapture             matlab.ui.control.Label
        Label_sEMG                      matlab.ui.control.Label
        Label_MIDI                      matlab.ui.control.Label
        Label_BloodOxygention           matlab.ui.control.Label
        MotionCapturematButton          matlab.ui.control.Button
        sEMGsensortxtButton             matlab.ui.control.Button
        MIDIcapturewavButton            matlab.ui.control.Button
        BloodOxygentionSensormatButton  matlab.ui.control.Button
        PlotTab                         matlab.ui.container.Tab
        SETTINGSButtonGroup             matlab.ui.container.ButtonGroup
        plot_options_channel1_sEMG_color  matlab.ui.control.Button
        plot_options_channel1_BloodOxygention_Color  matlab.ui.control.Button
        plot_options_channel2_sEMG_color  matlab.ui.control.Button
        plot_options_channel2_BloodOxygention_color  matlab.ui.control.Button
        plot_options_channel2_BloodOxygention  matlab.ui.control.CheckBox
        plot_options_channel2_sEMG      matlab.ui.control.CheckBox
        Channel1Label                   matlab.ui.control.Label
        Channel2Label                   matlab.ui.control.Label
        plot_options_channel1_BloodOxygention  matlab.ui.control.CheckBox
        plot_options_channel1_sEMG      matlab.ui.control.CheckBox
        graph                           matlab.ui.control.UIAxes
    end

    
    %INPUT
    properties (Access = private)
        %FILES
        SynchronisationSignalFile 
        BloodOxygentionSensorFile
        sEMGsensorFile
        sEMGMVCFile 
        MotionCaptureFile

        %MARKER DATA
        markerLocation; 
        markerTolerance; 
        
        %SAMPLE RATES
        sEMG_sample_rate;
        BloodOxygention_sample_rate;
        MotionCapture_sample_rate;
        base_sample_rate;
        frame_size;
        
        %working directory
        appFolder
        lastInputFilePath
        
    end
    
    %DATA
    properties(Access = public)
        Data_Motion;
        Data_Channel1_sEMG;
        Data_Channel2_sEMG;
        Data_Channel1_Blood;
        Data_Channel2_Blood;
    end


    %PLOT
    properties(Access = public)
        
        %toggle for plots
        setOfPlots

        %colors
        channel1_sMEGColor           = [0.9882, 0.4745, 0.3451]
        channel1_BloodOxygentionColor= [0.6039, 0.8275, 0.2235]

        channel2_sMEGColor           = [0.9882, 0.4745, 0.3451]
        channel2_BloodOxygentionColor= [0.6039, 0.8275, 0.2235]
    end


    methods (Access = private)
        
        
        function isSaved = saveCoordinates(app)
            coords = struct('X', app.markerLocation.X, 'Y', app.markerLocation.Y, 'Z', app.markerLocation.Z);
            jsonStr = jsonencode(coords);

            filename = 'coordinates.json';
            jsonFilePath = fullfile(app.appFolder, filename);
            fid = fopen(jsonFilePath, 'w');
            if fid == -1
                error('Cannot open file %s for writing.', filename);
            end

            fprintf(fid, '%s', jsonStr);
            fclose(fid);
        end

        function isSaved = saveTolerances(app)
            coords = struct('X', app.markerTolerance.X, 'Y', app.markerTolerance.Y, 'Z', app.markerTolerance.Z);
            jsonStr = jsonencode(coords);

            filename = 'tolerance.json';
            jsonFilePath = fullfile(app.appFolder, filename);
            fid = fopen(jsonFilePath, 'w');
            if fid == -1
                error('Cannot open file %s for writing.', filename);
            end

            fprintf(fid, '%s', jsonStr);
            fclose(fid);
        end

        function isLoaded = loadCoordinates(app)
            filename = 'coordinates.json';
            jsonFilePath = fullfile(app.appFolder, filename);
            fid = fopen(jsonFilePath, 'r');
            if fid == -1
                isLoaded = false;
                return;
            end
            
            rawData = fread(fid, inf, '*char')';
            fclose(fid);
            
            app.markerLocation = jsondecode(rawData);
           
            % Display the loaded data
            % disp('Loaded coordinates:');
            % disp(['X: ' num2str(app.markerLocation.X)]);
            % disp(['Y: ' num2str(app.markerLocation.Y)]);
            % disp(['Z: ' num2str(app.markerLocation.Z)]);
            
            isLoaded = true;

        end

        function isSaved = loadTolerances(app)
            filename = 'tolerance.json';
            jsonFilePath = fullfile(app.appFolder, filename);
            fid = fopen(jsonFilePath, 'r');
            if fid == -1
                isSaved = false;
                return;
            end
            
            rawData = fread(fid, inf, '*char')';
            fclose(fid);
            
            app.markerTolerance = jsondecode(rawData);
           
            % Display the loaded data
            % disp('Loaded Tolerance:');
            % disp(['X: ' num2str(app.markerLocation.X)]);
            % disp(['Y: ' num2str(app.markerLocation.Y)]);
            % disp(['Z: ' num2str(app.markerLocation.Z)]);
            
            isSaved = true;

        end

        function reFreshplot(app)
            cla(app.graph);
            hold(app.graph, "on");
            legends = [];

            %Channel 1
            if (app.setOfPlots("channel1_BloodOxygention"))
                plot(app.graph, app.Data_Channel1_Blood, "-.", "Color", app.channel1_BloodOxygentionColor);
                legends = [legends, "Channel1: BloodOxygention in %"];
            end

            if (app.setOfPlots("channel1_sEMG"))
                plot(app.graph, app.Data_Channel1_sEMG, "-.", "Color",app.channel1_sMEGColor);
                legends = [legends, "Channel1: sEMG"];
            end

            %Channel 2
            if (app.setOfPlots("channel2_BloodOxygention"))
                plot(app.graph, app.Data_Channel2_Blood, "Color", app.channel2_BloodOxygentionColor);
                legends = [legends, "Channel2: BloodOxygention in %"];
            end

            if (app.setOfPlots("channel2_sEMG"))
                plot(app.graph, app.Data_Channel2_sEMG, "Color",app.channel2_sMEGColor);
                legends = [legends, "Channel2: sEMG"];
            end

            legend(app.graph, legends)
            hold(app.graph, "off");

        end

        function saveData(app) 
            arrays  = {app.Data_Channel1_sEMG, app.Data_Channel2_sEMG, app.Data_Channel1_Blood, app.Data_Channel2_Blood, app.Data_Motion};
            columnNames = {'sEMG ch1 (mV)', 'sEMG ch2 (mV)', 'Blood Oxygen ch1 (%)', 'Blood Oxygen ch2 (%)', 'Motion range'};

            %creating a filename
            currentDateTime = datetime('now', 'Format', 'yyyy-MM-dd_HH-mm-ss');
            fileName = sprintf('output_%s.csv', currentDateTime); 
            %making the file
            outputFilePath = fullfile(app.appFolder, fileName);
            fid = fopen(outputFilePath, 'w');
            
            % Write the column headers
            fprintf(fid, '%s,', columnNames{:});
            fprintf(fid, '\n');

            % Find the maximum length of the arrays
            maxLength = max(cellfun(@length, arrays));
            
            % Write each array to the file, row by row
            for i = 1:maxLength
                for j = 1:length(arrays)
                    if i <= length(arrays{j})
                        fprintf(fid, '%g,', arrays{j}(i));
                    else
                        fprintf(fid, ',');
                    end
                end
                fprintf(fid, '\n');
            end
            fclose(fid);
          
            outputMessage = ['Output has been saved to:'  outputFilePath];
            msgbox(outputMessage, 'Output Location', 'help');
        end

        function emgData = emgEdits(app, data, MVC, skipMVC)
            temp = EMG_to_mV(data);
            temp = EMG_filter(temp);
            temp = EMG_to_envelope(temp);

            if ~skipMVC
                temp = EMG_normalize(temp, EMG_to_mV(MVC));
            end

            emgData = temp;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            if isdeployed
                % If the code is running as a standalone executable
                app.appFolder = ctfroot;
            else
                % If the code is running within MATLAB
                app.appFolder = pwd;
            end


            app.lastInputFilePath = app.appFolder;

            if (app.loadCoordinates() == false)
                app.markerLocation = struct('X', 0, 'Y', 0, 'Z', 0);
            end

            if (app.loadTolerances() == false)
                app.markerTolerance = struct('X', 0, 'Y', 0, 'Z', 0);
            end

            app.MotionCapture_X_EditField.Value = app.markerLocation.X;
            app.MotionCapture_Y_EditField.Value = app.markerLocation.Y;
            app.MotionCapture_Z_EditField.Value = app.markerLocation.Z;

            app.MotionCaptureTol_X_EditField.Value = app.markerTolerance.X;
            app.MotionCaptureTol_Y_EditField.Value = app.markerTolerance.Y;
            app.MotionCaptureTol_Z_EditField.Value = app.markerTolerance.Z;


            app.sEMG_sample_rate = app.sEMG_EditField.Value;
            app.BloodOxygention_sample_rate = app.BloodOxygention_EditField.Value;
            app.MotionCapture_sample_rate = app.MotionCapture_EditField.Value;
            app.base_sample_rate = app.Base_EditField.Value;
            app.frame_size = app.FrameSIze_EditField.Value;


            app.plot_options_channel1_BloodOxygention_Color.BackgroundColor = app.channel1_BloodOxygentionColor;
            app.plot_options_channel1_sEMG_color.BackgroundColor = app.channel1_sMEGColor;

            app.plot_options_channel2_BloodOxygention_color.BackgroundColor =  app.channel1_BloodOxygentionColor;
            app.plot_options_channel2_sEMG_color.BackgroundColor =             app.channel2_sMEGColor;

        end

        % Button pushed function: BloodOxygentionSensormatButton
        function BloodOxygentionSensormatButtonPushed(app, event)
            [baseName, folder] = uigetfile(".mat", "select a file", app.lastInputFilePath);
            if ischar(baseName) && ischar(folder) && ~isempty(baseName)
                app.lastInputFilePath = folder;
                [~, ~, ext] = fileparts(baseName);
                if strcmpi(ext, '.mat') %check if the right type of file
                    fullFileName = fullfile(folder, baseName);
                    app.BloodOxygentionSensorFile = fullFileName;
                    app.Label_BloodOxygention.Text = baseName;
                else
                    msgbox('The selected file is not a .mat file.', 'Error', 'error');
                end
            end
        end

        % Button pushed function: MIDIcapturewavButton
        function MIDIcapturewavButtonPushed(app, event)
            [baseName, folder] = uigetfile(".wav", "select a file", app.lastInputFilePath);
            if ischar(baseName) && ischar(folder) && ~isempty(baseName)
                app.lastInputFilePath = folder;
                 [~, ~, ext] = fileparts(baseName);
                 if strcmpi(ext, '.wav') %check if the right type of file
                    fullFileName = fullfile(folder, baseName);
                    app.SynchronisationSignalFile = fullFileName;
                    app.Label_MIDI.Text = baseName;
                 else
                    msgbox('The selected file is not a .wav file.', 'Error', 'error');
                 end
            end
        end

        % Button pushed function: sEMGsensortxtButton
        function sEMGsensortxtButtonPushed(app, event)
            [baseName, folder] = uigetfile(".txt", "select a file",  app.lastInputFilePath);
            if ischar(baseName) && ischar(folder) && ~isempty(baseName)
                app.lastInputFilePath = folder;
                [~, ~, ext] = fileparts(baseName);
                if strcmpi(ext, '.txt') %check if the right type of file
                    fullFileName = fullfile(folder, baseName);
                    app.sEMGsensorFile = fullFileName;
                    app.Label_sEMG.Text = baseName;
                else
                    msgbox('The selected file is not a .txt file.', 'Error', 'error');
                end
            end
        end

        % Button pushed function: MotionCapturematButton
        function MotionCapturematButtonPushed(app, event)
            [baseName, folder] = uigetfile(".mat", "select a file", app.lastInputFilePath);
            if ischar(baseName) && ischar(folder) && ~isempty(baseName)
                app.lastInputFilePath = folder;
                [~, ~, ext] = fileparts(baseName);
                if strcmpi(ext, '.mat') %check if the right type of file
                    fullFileName = fullfile(folder, baseName);
                    app.MotionCaptureFile = fullFileName;
                    app.Label_MotionCapture.Text = baseName;
                else
                    msgbox('The selected file is not a .mat file.', 'Error', 'error');
                end
            end
        end

        % Button pushed function: sEMGMVCsensortxtButton
        function sEMGMVCsensortxtButtonPushed(app, event)
            [baseName, folder] = uigetfile(".txt", "select a file", app.lastInputFilePath);
            if ischar(baseName) && ischar(folder) && ~isempty(baseName)
                 app.lastInputFilePath = folder;
                 [~, ~, ext] = fileparts(baseName);
                 if strcmpi(ext, '.txt') %check if the right type of file
                    fullFileName = fullfile(folder, baseName);
                    app.sEMGMVCFile = fullFileName;
                    app.Label_sEMG_MVC.Text = baseName;
                 else
                    msgbox('The selected file is not a .txt file.', 'Error', 'error');
                 end
            end
        end

        % Value changed function: MotionCapture_X_EditField
        function MotionCapture_X_EditFieldValueChanged(app, event)
            value = app.MotionCapture_X_EditField.Value;
            app.markerLocation.X = value;
            app.saveCoordinates();
        end

        % Value changed function: MotionCapture_Y_EditField
        function MotionCapture_Y_EditFieldValueChanged(app, event)
            value = app.MotionCapture_Y_EditField.Value;
            app.markerLocation.Y = value;
            app.saveCoordinates();
        end

        % Value changed function: MotionCapture_Z_EditField
        function MotionCapture_Z_EditFieldValueChanged(app, event)
            value = app.MotionCapture_Z_EditField.Value;
            app.markerLocation.Z = value;
            app.saveCoordinates();
        end

        % Value changed function: MotionCaptureTol_X_EditField
        function MotionCaptureTol_X_EditFieldValueChanged(app, event)
            value = app.MotionCaptureTol_X_EditField.Value;
            app.markerTolerance.X = value;
            app.saveTolerances();
        end

        % Value changed function: MotionCaptureTol_Y_EditField
        function MotionCaptureTol_Y_EditFieldValueChanged(app, event)
            value = app.MotionCaptureTol_Y_EditField.Value;
            app.markerTolerance.Y = value;
            app.saveTolerances();
        end

        % Value changed function: MotionCaptureTol_Z_EditField
        function MotionCaptureTol_Z_EditFieldValueChanged(app, event)
            value = app.MotionCaptureTol_Z_EditField.Value;
            app.markerTolerance.Z = value;
            app.saveTolerances();
        end

        % Button down function: PlotTab
        function PlotTabButtonDown(app, event)
        setOfPlotNames = {
            "channel1_BloodOxygention", 
            "channel2_BloodOxygention", 
            "channel1_sEMG",
            "channel2_sEMG"};
        
        app.setOfPlots = configureDictionary("string", "logical");
        for i = 1:length(setOfPlotNames)
            key = setOfPlotNames{i};
            if ~(app.setOfPlots.isKey(key))
                app.setOfPlots = insert(app.setOfPlots, key, false);
            end
        end
        end

        % Value changed function: plot_options_channel1_sEMG
        function plot_options_channel1_sEMGValueChanged(app, event)
            app.setOfPlots("channel1_sEMG") = app.plot_options_channel1_sEMG.Value;
            app.reFreshplot(); 
        end

        % Button pushed function: plot_options_channel1_sEMG_color
        function plot_options_channel1_sEMG_colorButtonPushed(app, event)
            c = uisetcolor;
            if ~isequal(c, 0)
            app.plot_options_channel1_sEMG_color.BackgroundColor = c;
            app.channel1_sMEGColor = c;
            app.reFreshplot();
            end
        end

        % Value changed function: plot_options_channel2_sEMG
        function plot_options_channel2_sEMGValueChanged(app, event)
            app.setOfPlots("channel2_sEMG") = app.plot_options_channel2_sEMG.Value;
            app.reFreshplot();            
        end

        % Button pushed function: plot_options_channel2_sEMG_color
        function plot_options_channel2_sEMG_colorButtonPushed(app, event)
            c = uisetcolor;
            if ~isequal(c, 0)
            app.plot_options_channel1_sEMG_color.BackgroundColor = c;
            app.channel1_sMEGColor = c;
            app.reFreshplot();
            end
        end

        % Value changed function: plot_options_channel1_BloodOxygention
        function plot_options_channel1_BloodOxygentionValueChanged(app, event)
            app.setOfPlots("channel1_BloodOxygention") = app.plot_options_channel1_BloodOxygention.Value;
            app.reFreshplot();
        end

        % Button pushed function: 
        % plot_options_channel1_BloodOxygention_Color
        function plot_options_channel1_BloodOxygention_ColorPushed(app, event)
            c = uisetcolor;
            if ~isequal(c, 0)
            app.plot_options_channel1_BloodOxygention_Color.BackgroundColor = c;
            app.channel1_BloodOxygentionColor = c;
            app.reFreshplot();
            end
        end

        % Value changed function: plot_options_channel2_BloodOxygention
        function plot_options_channel2_BloodOxygentionValueChanged(app, event)
            app.setOfPlots("channel2_BloodOxygention") = app.plot_options_channel2_BloodOxygention.Value;
            app.reFreshplot();
        end

        % Button pushed function: 
        % plot_options_channel2_BloodOxygention_color
        function plot_options_channel2_BloodOxygention_colorButtonPushed(app, event)
            c = uisetcolor;
            if ~isequal(c, 0)
            app.plot_options_channel1_BloodOxygention_color.BackgroundColor = c;
            app.channel1_BloodOxygentionColor = c;
            app.reFreshplot();
            end
        end

        % Button pushed function: SynchronizeButton
        function SynchronizeButtonPushed(app, event)
            files = {app.SynchronisationSignalFile, app.BloodOxygentionSensorFile, app.sEMGsensorFile, app.sEMGMVCFile, app.MotionCaptureFile};

            fileNames = {'SynchronisationSignalFile', 'BloodOxygentionSensorFile', 'sEMGsensorFile', 'sEMGMVCFile', 'MotionCaptureFile'};

            missingFiles = {};
            for i = 1:length(files)
                if isempty(files{i})
                    missingFiles{end+1} = fileNames{i};
                end
            end
        
            skipMVC = false;
             if ~isempty(missingFiles)
                if length(missingFiles) == 1 && strcmp(missingFiles{1}, 'sEMGMVCFile')
                    % Ask if the user still wants to continue if only sEMGMVCFile is missing
                    choice = questdlg('sEMGMVCFile is missing. Do you still want to continue?', ...
                                       'Missing sEMGMVCFile', ...
                                        'Yes','No','No');
                    switch choice
                        case 'Yes'
                            msgbox('Continuing without sEMGMVCFile.', 'File Check', 'warn');
                            skipMVC = true;
                        case 'No'
                            msgbox('Operation cancelled.', 'File Check', 'error');
                            return;
                    end
                else
                    % If any other files are missing, display an error and do not continue
                    missingFilesList = strjoin(missingFiles, ', ');
                    msgbox(['The following files are missing: ', missingFilesList], 'Missing Files', 'error');
                    return;
                end
             end

            try
                hWaitbar = waitbar(0, 'Please wait, processing...');
                numOutputs = 4;

                pool = gcp('nocreate');
                if isempty(pool)
                    pool = parpool('local', 4);  
                end

                future = parfeval(pool, @extractData, numOutputs,...
                    app.SynchronisationSignalFile, ...
                    app.sEMGsensorFile, app.sEMG_sample_rate, ...
                    app.BloodOxygentionSensorFile, app.BloodOxygention_sample_rate, ...
                    app.MotionCaptureFile, app.MotionCapture_sample_rate, [app.markerLocation.X app.markerLocation.Y app.markerLocation.Z], [app.markerTolerance.X app.markerTolerance.Y app.markerTolerance.Z], ...
                    app.base_sample_rate, app.frame_size, 0);
                
                
                barnumber = 0;

                % Keep updating the waitbar until the task is complete
                while ~strcmp(future.State, 'finished')
                    barnumber = barnumber + rand()/100;
                    if barnumber >= 0.99
                        barnumber = 0.99;
                    end
                    waitbar(barnumber, hWaitbar, 'Processing...');
                    pause(0.5); 
                end
                
                % Retrieve the result
                [midi, sEMG, blood, motion] = fetchOutputs(future);
                
            catch
                try
                [midi, sEMG, blood, motion] = extractData(app.SynchronisationSignalFile, ...
                    app.sEMGsensorFile, app.sEMG_sample_rate, ...
                    app.BloodOxygentionSensorFile, app.BloodOxygention_sample_rate, ...
                    app.MotionCaptureFile, app.MotionCapture_sample_rate, [app.markerLocation.X app.markerLocation.Y app.markerLocation.Z], [app.markerTolerance.X app.markerTolerance.Y app.markerTolerance.Z], ...
                    app.base_sample_rate, app.frame_size, 0);
                catch ME %one last try catch for any other errors I can't even think off
                    errMsg = sprintf('Error: %s\n\nFile: %s\nLine: %d', ME.message, ME.stack(1).file, ME.stack(1).line);
                    msgbox(errMsg, 'Error', 'error');
                    return
                end

            end
            
            if ~skipMVC
                mvcData = readmatrix(app.sEMGMVCFile, 'Range', 4, 'Delimiter', '\t');
                mvc_muscle1 = mvcData(:, 4);
                mvc_muscle2 = mvcData(:, 5);
            else
                mvc_muscle1 = 1;
                mvc_muscle2 = 1;
            end
            
            app.Data_Motion = motion.range;
            app.Data_Channel1_Blood = (blood.data(1, :)./5).*100;
            app.Data_Channel2_Blood = (blood.data(2, :)./5).*100;
            app.Data_Channel1_sEMG = app.emgEdits(sEMG.data(1, :), mvc_muscle1, skipMVC);
            app.Data_Channel2_sEMG = app.emgEdits(sEMG.data(2, :), mvc_muscle2, skipMVC);


            app.saveData();

            try
            waitbar(1, hWaitbar, 'Done!');
            pause(0.1);
            close(hWaitbar);
            catch
            end


        end

        % Value changed function: sEMG_EditField
        function sEMG_EditFieldValueChanged(app, event)
        app.MotionCapture_sample_rate = app.sEMG_EditField.Value;
        end

        % Value changed function: BloodOxygention_EditField
        function BloodOxygention_EditFieldValueChanged(app, event)
        app.BloodOxygention_sample_rate = app.BloodOxygention_EditField.Value;
        end

        % Value changed function: MotionCapture_EditField
        function MotionCapture_EditFieldValueChanged(app, event)
        app.MotionCapture_sample_rate = app.MotionCapture_EditField.Value;
        end

        % Value changed function: Base_EditField
        function Base_EditFieldValueChanged(app, event)
        app.base_sample_rate = app.Base_EditField.Value;
        end

        % Value changed function: FrameSIze_EditField
        function FrameSIze_EditFieldValueChanged(app, event)
        app.frame_size = app.FrameSIze_EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.GridLayout2 = uigridlayout(app.InputTab);
            app.BloodOxygentionSensormatButton = uibutton(app.GridLayout2, 'push');
            app.BloodOxygentionSensormatButton.ButtonPushedFcn = createCallbackFcn(app, @BloodOxygentionSensormatButtonPushed, true);
            app.BloodOxygentionSensormatButton.Text = {'Blood Oxygention'; 'Sensor (.mat)'};
            app.MIDIcapturewavButton = uibutton(app.GridLayout2, 'push');
            app.MIDIcapturewavButton.ButtonPushedFcn = createCallbackFcn(app, @MIDIcapturewavButtonPushed, true);
            app.MIDIcapturewavButton.Text = 'MIDI capture (.wav)';
            app.sEMGsensortxtButton = uibutton(app.GridLayout2, 'push');
            app.sEMGsensortxtButton.ButtonPushedFcn = createCallbackFcn(app, @sEMGsensortxtButtonPushed, true);
            app.sEMGsensortxtButton.Text = 'sEMG sensor (.txt)';
            app.MotionCapturematButton = uibutton(app.GridLayout2, 'push');
            app.MotionCapturematButton.ButtonPushedFcn = createCallbackFcn(app, @MotionCapturematButtonPushed, true);
            app.MotionCapturematButton.Text = 'Motion Capture (.mat)';
            app.Label_BloodOxygention = uilabel(app.GridLayout2);
            app.Label_BloodOxygention.Text = 'file name';
            app.Label_MIDI = uilabel(app.GridLayout2);
            app.Label_MIDI.Text = 'file name';
            app.Label_sEMG = uilabel(app.GridLayout2);
            app.Label_sEMG.Text = 'file name';
            app.Label_MotionCapture = uilabel(app.GridLayout2);
            app.Label_MotionCapture.Text = 'file name';
            app.SynchronizeButton = uibutton(app.GridLayout2, 'push');
            app.SynchronizeButton.ButtonPushedFcn = createCallbackFcn(app, @SynchronizeButtonPushed, true);
            app.SynchronizeButton.Text = 'Synchronize';
            app.MotionCaptureMarkerCoordinatesPanel = uipanel(app.GridLayout2);
            app.MotionCaptureMarkerCoordinatesPanel.Title = 'Motion Capture Marker Coordinates';
            app.GridLayout = uigridlayout(app.MotionCaptureMarkerCoordinatesPanel);
            app.MotionCapture_X_EditField = uieditfield(app.GridLayout, 'numeric');
            app.MotionCapture_X_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapture_X_EditFieldValueChanged, true);
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout);
            app.ZLabel.Text = 'Z';
            app.MotionCapture_Y_EditField = uieditfield(app.GridLayout, 'numeric');
            app.MotionCapture_Y_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapture_Y_EditFieldValueChanged, true);
            app.MotionCapture_Z_EditField = uieditfield(app.GridLayout, 'numeric');
            app.MotionCapture_Z_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapture_Z_EditFieldValueChanged, true);
            app.MotionCaptureMarkerTolerancePanel = uipanel(app.GridLayout2);
            app.MotionCaptureMarkerTolerancePanel.Title = 'Motion Capture Marker Tolerance';
            app.GridLayout_2 = uigridlayout(app.MotionCaptureMarkerTolerancePanel);
            app.XLabel_2 = uilabel(app.GridLayout_2);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.GridLayout_2);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.GridLayout_2);
            app.ZLabel_2.Text = 'Z';
            app.MotionCaptureTol_X_EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.MotionCaptureTol_X_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureTol_X_EditFieldValueChanged, true);
            app.MotionCaptureTol_Y_EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.MotionCaptureTol_Y_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureTol_Y_EditFieldValueChanged, true);
            app.MotionCaptureTol_Z_EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.MotionCaptureTol_Z_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureTol_Z_EditFieldValueChanged, true);
            app.SampleRatesPanel = uipanel(app.GridLayout2);
            app.SampleRatesPanel.Title = 'Sample Rates';
            app.GridLayout_3 = uigridlayout(app.SampleRatesPanel);
            app.sEMG_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.sEMG_EditField.ValueChangedFcn = createCallbackFcn(app, @sEMG_EditFieldValueChanged, true);
            app.sEMG_EditField.Value = 1000;
            app.sEMG_Label = uilabel(app.GridLayout_3);
            app.sEMG_Label.Text = 'sEMG';
            app.BloodOxygention_Label = uilabel(app.GridLayout_3);
            app.BloodOxygention_Label.Text = 'Blood Oxygention';
            app.MotionCaptureLabel = uilabel(app.GridLayout_3);
            app.MotionCaptureLabel.Text = 'Motion Capture';
            app.BloodOxygention_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.BloodOxygention_EditField.ValueChangedFcn = createCallbackFcn(app, @BloodOxygention_EditFieldValueChanged, true);
            app.BloodOxygention_EditField.Value = 1000;
            app.MotionCapture_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.MotionCapture_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapture_EditFieldValueChanged, true);
            app.MotionCapture_EditField.Value = 300;
            app.Base_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.Base_EditField.ValueChangedFcn = createCallbackFcn(app, @Base_EditFieldValueChanged, true);
            app.Base_EditField.Value = 1000;
            app.FrameSIzeLabel = uilabel(app.GridLayout_3);
            app.FrameSIzeLabel.Text = 'Frame SIze';
            app.FrameSIze_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.FrameSIze_EditField.ValueChangedFcn = createCallbackFcn(app, @FrameSIze_EditFieldValueChanged, true);
            app.FrameSIze_EditField.Value = 5001;
            app.Base_Label = uilabel(app.GridLayout_3);
            app.Base_Label.Text = 'Base';
            app.sEMGMVCsensortxtButton = uibutton(app.GridLayout2, 'push');
            app.sEMGMVCsensortxtButton.ButtonPushedFcn = createCallbackFcn(app, @sEMGMVCsensortxtButtonPushed, true);
            app.sEMGMVCsensortxtButton.Text = 'sEMG MVC sensor (.txt)';
            app.Label_sEMG_MVC = uilabel(app.GridLayout2);
            app.Label_sEMG_MVC.Text = 'file name';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.PlotTab.ButtonDownFcn = createCallbackFcn(app, @PlotTabButtonDown, true);
            app.graph = uiaxes(app.PlotTab);
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            zlabel(app.graph, 'Z')
            app.SETTINGSButtonGroup = uibuttongroup(app.PlotTab);
            app.SETTINGSButtonGroup.Title = 'SETTINGS';
            app.plot_options_channel1_sEMG = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_channel1_sEMG.ValueChangedFcn = createCallbackFcn(app, @plot_options_channel1_sEMGValueChanged, true);
            app.plot_options_channel1_sEMG.Text = 'sEMG ';
            app.plot_options_channel1_BloodOxygention = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_channel1_BloodOxygention.ValueChangedFcn = createCallbackFcn(app, @plot_options_channel1_BloodOxygentionValueChanged, true);
            app.plot_options_channel1_BloodOxygention.Text = 'Blood Oxygention';
            app.Channel2Label = uilabel(app.SETTINGSButtonGroup);
            app.Channel2Label.Text = 'Channel 2';
            app.Channel1Label = uilabel(app.SETTINGSButtonGroup);
            app.Channel1Label.Text = 'Channel 1';
            app.plot_options_channel2_sEMG = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_channel2_sEMG.ValueChangedFcn = createCallbackFcn(app, @plot_options_channel2_sEMGValueChanged, true);
            app.plot_options_channel2_sEMG.Text = 'sEMG ';
            app.plot_options_channel2_BloodOxygention = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_channel2_BloodOxygention.ValueChangedFcn = createCallbackFcn(app, @plot_options_channel2_BloodOxygentionValueChanged, true);
            app.plot_options_channel2_BloodOxygention.Text = 'Blood Oxygention';
            app.plot_options_channel2_BloodOxygention_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_channel2_BloodOxygention_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_channel2_BloodOxygention_colorButtonPushed, true);
            app.plot_options_channel2_BloodOxygention_color.Text = '';
            app.plot_options_channel2_sEMG_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_channel2_sEMG_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_channel2_sEMG_colorButtonPushed, true);
            app.plot_options_channel2_sEMG_color.Text = '';
            app.plot_options_channel1_BloodOxygention_Color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_channel1_BloodOxygention_Color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_channel1_BloodOxygention_ColorPushed, true);
            app.plot_options_channel1_BloodOxygention_Color.Text = '';
            app.plot_options_channel1_sEMG_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_channel1_sEMG_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_channel1_sEMG_colorButtonPushed, true);
            app.plot_options_channel1_sEMG_color.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = piano

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end